classdef pab_txfmr_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        NodeImpedanceOhmEditField       matlab.ui.control.EditField
        NodeImpedanceOhmEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        A_LnHN2EditField                matlab.ui.control.NumericEditField
        A_LnHN2EditFieldLabel           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        NsResultLabel                   matlab.ui.control.Label
        NpResultLabel                   matlab.ui.control.Label
        NsLabel                         matlab.ui.control.Label
        NpLabel                         matlab.ui.control.Label
        SourceImpedanceOhmEditField     matlab.ui.control.NumericEditField
        SourceImpedanceOhmEditFieldLabel  matlab.ui.control.Label
        FrequencykHzEditField           matlab.ui.control.NumericEditField
        FrequencykHzEditFieldLabel      matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function node_imp = parseNodeImpedance(app)
            str = app.NodeImpedanceOhmEditField.Value;
            node_imp = str2double(str);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            nimp = parseNodeImpedance(app);
            simp = app.SourceImpedanceOhmEditField.Value;
            A_L = app.A_LnHN2EditField.Value*1e-9;
            f = app.FrequencykHzEditField.Value*1e3;
            w = 2*pi*f;
            
            Znr = real(nimp);
            Zni = imag(nimp);

            Lm = -abs(nimp)^2/(Zni*w);

            Zeq_r = (w*Lm*(Znr*(Zni+w*Lm)-Zni*Znr))/(Znr^2+(Zni+w*Lm)^2);

            Np = sqrt(Lm/A_L);
            Ns = Np*sqrt(simp/Zeq_r);

            app.NsResultLabel.Text = num2str(Ns,3);
            app.NpResultLabel.Text = num2str(Np,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FrequencykHzEditFieldLabel = uilabel(app.GridLayout);
            app.FrequencykHzEditFieldLabel.Text = 'Frequency (kHz)';
            app.FrequencykHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrequencykHzEditField.Value = 20;
            app.SourceImpedanceOhmEditFieldLabel = uilabel(app.GridLayout);
            app.SourceImpedanceOhmEditFieldLabel.Text = 'Source Impedance (Ohm)';
            app.SourceImpedanceOhmEditField = uieditfield(app.GridLayout, 'numeric');
            app.SourceImpedanceOhmEditField.Value = 50;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NpLabel = uilabel(app.GridLayout2);
            app.NpLabel.Text = 'Np =';
            app.NsLabel = uilabel(app.GridLayout2);
            app.NsLabel.Text = 'Ns =';
            app.NpResultLabel = uilabel(app.GridLayout2);
            app.NpResultLabel.Text = '?';
            app.NsResultLabel = uilabel(app.GridLayout2);
            app.NsResultLabel.Text = '?';
            app.A_LnHN2EditFieldLabel = uilabel(app.GridLayout);
            app.A_LnHN2EditFieldLabel.Text = 'A_L (nH/N^2)';
            app.A_LnHN2EditField = uieditfield(app.GridLayout, 'numeric');
            app.A_LnHN2EditField.Value = 250;
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NodeImpedanceOhmEditFieldLabel = uilabel(app.GridLayout);
            app.NodeImpedanceOhmEditFieldLabel.Text = 'Node Impedance (Ohm)';
            app.NodeImpedanceOhmEditField = uieditfield(app.GridLayout, 'text');
            app.NodeImpedanceOhmEditField.Value = '50-j*220';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pab_txfmr_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end